classdef contrastThresholds < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure1            matlab.ui.Figure
    axes1              matlab.ui.control.UIAxes
    stimRepsText       matlab.ui.control.EditField
    intertrialDurText  matlab.ui.control.EditField
    prestimDurText     matlab.ui.control.EditField
    stimDurText        matlab.ui.control.EditField
    baseContrastMenu   matlab.ui.control.DropDown
    clearDataButton    matlab.ui.control.Button
    savePlotsButton    matlab.ui.control.Button
    saveDataButton     matlab.ui.control.Button
    runButton          matlab.ui.control.Button
    staticText1        matlab.ui.control.Label
    staticText2        matlab.ui.control.Label
    staticText3        matlab.ui.control.Label
    statusText         matlab.ui.control.Label
    resultsTable       matlab.ui.control.Table
    text21             matlab.ui.control.Label
    text22             matlab.ui.control.Label
    text23             matlab.ui.control.Label
    showHideButton     matlab.ui.control.Button
    loadDataButton     matlab.ui.control.Button
    testWarning        matlab.ui.control.Label
    stimWarning        matlab.ui.control.Label
  end

  properties (Access = public)
    testMode          % no user responses
    taskState         % task running, idle, etc
    theKey            % last key pressed
  end
  
methods (Access = private)
  function doShowHide(app, handles)

    if strcmp(get(app.showHideButton, 'Text'), 'Hide Display')
      if handles.data.doStimDisplay
        delete(handles.stimuli);
      end
      set(app.showHideButton, 'Text', 'Show Display', 'backgroundColor', 'red');
      set(app.runButton, 'Text', 'Run', 'backgroundColor', [0.9412 0.9412 0.9412]);
      ctControlState(app, 'off', {app.showHideButton});
    elseif strcmp(get(app.showHideButton, 'Text'), 'Show Display')
      if handles.data.doStimDisplay
        handles.stimuli = ctStimuli();                  % restore stimulus display
      end
      set(app.showHideButton, 'Text', 'Hide Display','backgroundColor', [0.9412 0.9412 0.9412]);
      set(app.runButton, 'Text', 'Run','backgroundColor', 'green');
      ctControlState(app, 'on', {app.showHideButton});
    end
    guidata(app.showHideButton, handles);               % save the changes
  end
 
  function keyPressed(app, f, keyData)
    fprintf('keyPressed\n');
    if strcmp(keyData.Key, 'escape')
      fprintf('escape key\n');
      if app.taskState ~= ctTaskState.taskStopped
        app.taskState = ctTaskState.taskStopRunning;
      end
    elseif app.taskState == ctTaskState.taskWaitGoKey
      if strcmp(keyData.Key, 'downarrow')
        fprintf('downarrow\n');
        app.taskState = ctTaskState.taskDoStim;
      end
    elseif app.taskState == ctTaskState.taskWaitResponse
      if strcmp(keyData.Key, 'leftarrow')
        fprintf('leftarrow\n');
        app.theKey = 'left';
        app.taskState = ctTaskState.taskProcessResponse;
      elseif strcmp(keyData.Key, 'rightarrow')
        fprintf('rightarrow\n');
        app.theKey = 'right';
        app.taskState = ctTaskState.taskProcessResponse;
      end
    end
  end
  
  function timerErrorFcn(~, ~, ~, ~)
    disp('timer error');
  end
  
  % Update components that require runtime configuration
  function addRuntimeConfigurations(app)
    
    % Set component properties that require runtime configuration
    app.resultsTable.BackgroundColor = [1 1 1; 0.9608 0.9608 0.9608];
    app.resultsTable.ColumnFormat = {[] [] [] [] []};
  end
end
    

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function openContrastThresholds(app, varargin)

      set(app.figure1, 'visible', 'off');      
      addRuntimeConfigurations(app);           % Add runtime required configuration - Added by Migration Tool
      [hObject, ~, handles] = convertToGUIDECallbackArguments(app);
       
      app.testMode = true;
      app.taskState = ctTaskState.taskStopped;
      app.theKey = '';
     
      rng('shuffle');
      handles.data = ctTaskData(app);
      handles.output = hObject;                                     % select default command line output
      if handles.data.doStimDisplay
        handles.stimuli = ctStimuli();                              % restore stimulus display
      end
      
      %     testStimuli(handles.stimuli, handles);                  % check the screen for contrasts available
      
      ctDrawStatusText(handles, 'idle');
      movegui(handles.figure1, [-10, 10]);
      if app.testMode
        set(app.testWarning, 'text', 'Test Mode');
      end
      if ~handles.data.doStimDisplay
        set(app.stimWarning, 'text', 'No Display');
      elseif ~handles.data.doStim
        set(app.stimWarning, 'text', 'No Stimuli');
      end
      buttonStruct.keyDown = '';                                            % UserDate needs to be a struct
      set(app.runButton, 'userData', buttonStruct);
      set(app.figure1, 'visible', 'on');

      guidata(hObject, handles);                                    % save the selection
      
%       KbName('UnifyKeyNames');
      taskTimer = timer('name', 'TaskTimer', 'executionMode', 'fixedRate', 'period', 0.1,...
        'UserData', handles, 'errorFcn', {@timerErrorFcnStop, handles}, 'timerFcn', {@ctTaskController, app});
       start(taskTimer);
     
      
      set(app.figure1, 'KeyPressFcn', @app.keyPressed)
%       keyTimer = timer('name', 'KeyTimer', 'executionMode', 'fixedRate', 'period', 0.1,...
%         'UserData', handles, 'errorFcn', {@timerErrorFcnStop, handles}, 'timerFcn', {@app.keyboardCheck});
%       start(keyTimer);
    end

    % Button pushed function: clearDataButton
    function clearDataCallback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);
      
      if strcmp(get(app.runButton, 'Text'), 'Stop')
        return;
      end
%       baseIndex = get(app.baseContrastMenu, 'value');
%       strings = get(app.baseContrastMenu, 'string');
      selection = uiconfirm(app.figure1, ...
            sprintf('Really clear data for %s base contrast?/n(This cannot be undone.)', ...
            get(app.baseContrastMenu, 'value')), 'Clear Data');
      switch selection
        case 'OK'
          clearOneContrast(handles.data, app);
          ctDrawHitRates(handles, true);
          guidata(hObject, handles);
        case 'Cancel'
          return
      end
    end

    % Button pushed function: loadDataButton
    function loadDataButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); 
      
      if handles.data.doStimDisplay
        cleanup(handles.stimuli);
      end
      ctControlState(app, 'off', {app.loadDataButton});
      [fileName, filePath] = uigetfile('*.mat', 'Load Matlab Data Workspace', '~/Desktop/');
      if fileName ~= 0
        stop(timerfind);                                            % stop/delete timers; pause data stream
        delete(timerfind);
        load([filePath fileName], 'd');
        handles.data = d;
        handles = ctDrawHitRates(handles, true);
        taskTimer = timer('name', 'TaskTimer', 'executionMode', 'fixedRate', 'period', 0.1,...
          'UserData', handles, 'errorFcn', {@timerErrorFcnStop, handles}, 'timerFcn', {@ctTaskController});
%         keyTimer = timer('name', 'KeyTimer', 'executionMode', 'fixedRate', 'period', 0.1,...
%           'UserData', handles, 'errorFcn', {@timerErrorFcnStop, handles}, 'timerFcn', {@app.keyboardCheck});
        start(taskTimer);
%         start(keyTimer);
      end
      if handles.data.doStimDisplay
        handles.stimuli = ctStimuli();                  % restore stimulus display
      end
      set(handles.runButton, 'backgroundColor', 'green');
      ctControlState(app, 'on', {app.loadDataButton});
      guidata(hObject, handles);                                      % save the selections
    end

    % Button pushed function: runButton
    function runButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); 
      
      if strcmp(get(hObject, 'String'), 'Stop')           % if we are running, just set the abort flag
        app.taskState = ctTaskState.taskStopRunning;
      else
        baseIndex = get(handles.baseContrastMenu, 'value');
        stimReps = str2double(get(handles.stimRepsText, 'string'));
        data = handles.data;
        if sum(data.trialsDone(baseIndex, :)) < stimReps * data.numIncrements
          app.taskState = ctTaskState.taskStartRunning;
          figure(app.figure1);                           % restore focus so key presses get registered
        else
          contrastStrings = get(handles.baseContrastMenu, 'string');
          originalSize = get(0, 'DefaultUIControlFontSize');
          set(0, 'DefaultUIControlFontSize', 14);
          helpdlg(sprintf('%d reps of %s contrast have already been done', stimReps, contrastStrings{baseIndex}));
          set(0, 'DefaultUIControlFontSize', originalSize);
        end
      end
      guidata(hObject, handles);                          % save the changes
    end

    % Button pushed function: saveDataButton
    function saveDataButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      
      if handles.data.doStimDisplay
        cleanup(handles.stimuli);
      end
      ctControlState(app, 'off', {app.saveDataButton});
      [fileName, filePath] = uiputfile('*.mat', 'Save Matlab Data Workspace', '~/Desktop/ContrastData.mat');
      if fileName ~= 0
        d = handles.data;
        save([filePath fileName], 'd');
      end
      if handles.data.doStimDisplay
        handles.stimuli = ctStimuli();
      end
      set(handles.runButton, 'backgroundColor', 'green');
      ctControlState(app, 'on', {app.saveDataButton});
    end

    % Button pushed function: savePlotsButton
    function savePlotsButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      
      %       if handles.data.doStimDisplay
      %         cleanup(handles.stimuli);
      %       end
      ctControlState(app, 'off', {});
      progress = uiprogressdlg(app.figure1, 'title', 'Save Plots', 'message', 'Saving to ~/Desktop/CTData', ...
        'indeterminate','on');
      
      timeString = savePlot(app.axes1, '~/Desktop/CTData/Plots/', 'CT', 'png');
      saveTable(app.resultsTable, '~/Desktop/CTData/Tables/', 'CT', timeString);
      close(progress);
      ctControlState(app, 'on', {});
      %             if handles.data.doStimDisplay
      %         handles.stimuli = ctStimuli();
      %       end
      %       set(handles.runButton, 'backgroundColor', 'green');
    end

    % Button pushed function: showHideButton
    function showHideButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      doShowHide(app, handles);
    end

    % Close request function: figure1
    function windowCloseRequest(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
      
      if strcmp(get(app.runButton, 'Text'), 'Stop')
        return;
      end
      selection = uiconfirm(app.figure1, 'Really exit? Unsaved data will be lost.', 'Exit Request');
      switch selection
        case 'OK'
          try stop(timerfind); catch, end
          try delete(timerfind); catch, end
          if handles.data.doStimDisplay
            try delete(handles.stimuli); catch, end
          end
          delete(app.figure1);                              % close the program window
        case 'Cancel'
          return
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.figure1 = uifigure('Visible', 'off');
      app.figure1.Name = 'NSCI 20100';
      app.figure1.CloseRequestFcn = createCallbackFcn(app, @windowCloseRequest, true);
      app.figure1.Tag = 'figure1';
      app.axes1 = uiaxes(app.figure1);
      app.axes1.XLim = [2 100];
      app.axes1.YLim = [0 100];
      app.axes1.XTick = [5 6 7 8 9 10 20 30 40 50 60 80 100];
      app.axes1.XTickLabel = {'5'; '6'; '7'; '8'; '9'; '10'; '20'; '30'; '40'; '50'; '60'; '80'; '100'};
      app.axes1.YTick = [0 20 40 60 80 100];
      app.axes1.YTickLabel = {'10'; '20'; '40'; '60'; '80'; '100'};
      app.axes1.XScale = 'log';
      app.axes1.Tag = 'axes1';
      app.stimRepsText = uieditfield(app.figure1, 'text');
      app.stimRepsText.Tag = 'stimRepsText';
      app.stimRepsText.Value = '25';
      app.intertrialDurText = uieditfield(app.figure1, 'text');
      app.intertrialDurText.Tag = 'intertrialDurText';
      app.intertrialDurText.Value = '1.0';
      app.prestimDurText = uieditfield(app.figure1, 'text');
      app.prestimDurText.Tag = 'prestimDurText';
      app.prestimDurText.Value = '1.0';
      app.stimDurText = uieditfield(app.figure1, 'text');
      app.stimDurText.Tag = 'stimDurText';
      app.stimDurText.Value = '0.25';
      app.baseContrastMenu = uidropdown(app.figure1);
      app.baseContrastMenu.Items = {'3%', '6%', '12%', '24%', '48%'};
      app.baseContrastMenu.Tag = 'baseContrastMenu';
      app.baseContrastMenu.Value = '48%';
      app.clearDataButton = uibutton(app.figure1, 'push');
      app.clearDataButton.ButtonPushedFcn = createCallbackFcn(app, @clearDataCallback, true);
      app.clearDataButton.Tag = 'clearDataButton';
      app.clearDataButton.Text = 'Clear Data';
      app.savePlotsButton = uibutton(app.figure1, 'push');
      app.savePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @savePlotsButton_Callback, true);
      app.savePlotsButton.Tag = 'savePlotsButton';
      app.savePlotsButton.Text = 'Save Plots';
      app.saveDataButton = uibutton(app.figure1, 'push');
      app.saveDataButton.ButtonPushedFcn = createCallbackFcn(app, @saveDataButton_Callback, true);
      app.saveDataButton.Tag = 'saveDataButton';
      app.saveDataButton.Text = 'Save Data';
      app.runButton = uibutton(app.figure1, 'push');
      app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButton_Callback, true);
      app.runButton.Tag = 'runButton';
      app.runButton.Text = 'Start';
      app.staticText1 = uilabel(app.figure1);
      app.staticText1.Tag = 'staticText1';
      app.staticText1.Text = 'Repeats Before Stop:';
      app.staticText2 = uilabel(app.figure1);
      app.staticText2.Tag = 'staticText2';
      app.staticText2.Text = 'Test Stim. Dur. (s):';
      app.staticText3 = uilabel(app.figure1);
      app.staticText3.Tag = 'staticText3';
      app.staticText3.Text = 'Base Stim. Dur. (s):';
      app.statusText = uilabel(app.figure1);
      app.statusText.Tag = 'statusText';
      app.statusText.Text = 'Status';
      app.resultsTable = uitable(app.figure1);
      app.resultsTable.ColumnName = {'3%'; '6%'; '12%'; '24%'; '48%'};
      app.resultsTable.RowName = {'Repeats Done'; 'Threshold Contrast'};
      app.resultsTable.ColumnEditable = [false false false false false];
      app.resultsTable.Tag = 'resultsTable';
      app.text21 = uilabel(app.figure1);
      app.text21.Tag = 'text21';
      app.text21.Text = 'Base Contrast:';
      app.text22 = uilabel(app.figure1);
      app.text22.Tag = 'text22';
      app.text22.Text = 'Inter-trial Dur. (s):';
      app.text23 = uilabel(app.figure1);
      app.text23.Tag = 'text23';
      app.text23.Text = {'NSCI 20100 Contrast Increment'; 'Thresholds Laboratory'};
      app.showHideButton = uibutton(app.figure1, 'push');
      app.showHideButton.ButtonPushedFcn = createCallbackFcn(app, @showHideButton_Callback, true);
      app.showHideButton.Tag = 'showHideButton';
      app.showHideButton.Text = 'Hide Display';
      app.loadDataButton = uibutton(app.figure1, 'push');
      app.loadDataButton.ButtonPushedFcn = createCallbackFcn(app, @loadDataButton_Callback, true);
      app.loadDataButton.Tag = 'loadDataButton';
      app.loadDataButton.Text = 'Load Data';
      app.testWarning = uilabel(app.figure1);
      app.testWarning.Tag = 'testWarning';
      app.testWarning.Text = '';
      app.stimWarning = uilabel(app.figure1);
      app.stimWarning.Tag = 'stimWarning';
      app.stimWarning.Text = '';
      % Show the figure after all components are created
      app.figure1.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = contrastThresholds(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.figure1)

      % Execute the startup function
      runStartupFcn(app, @(app)openContrastThresholds(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure1)
    end
  end
end