classdef SaccadeRT < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure1           matlab.ui.Figure
    posAxes           matlab.ui.control.UIAxes
    velAxes           matlab.ui.control.UIAxes
    gapAxes           matlab.ui.control.UIAxes
    stepAxes          matlab.ui.control.UIAxes
    overlapAxes       matlab.ui.control.UIAxes
    startButton       matlab.ui.control.Button
    clearButton       matlab.ui.control.Button
    viewDistanceText  matlab.ui.control.EditField
    text2             matlab.ui.control.Label
    thresholdDegText  matlab.ui.control.EditField
    text3             matlab.ui.control.Label
    savePlotsButton   matlab.ui.control.Button
    titleText         matlab.ui.control.Label
    saveDataButton    matlab.ui.control.Button
    loadDataButton    matlab.ui.control.Button
    warnText          matlab.ui.control.Label
    calibrationText   matlab.ui.control.Label
    Filter60Hz        matlab.ui.control.CheckBox
  end

  properties (Constant)
    
    degPerRadian = 57.2958;
    maxViewDistCM = 100;
    mmPerInch = 25.40;
    saccadeTraceS = 0.250;
    
    kNormal = 0;                    % task modes
    kDebug = 1;
    kTiming = 2;
    
    kCenteringTrial = 0;             % trial types
    kGapTrial = 1;
    kStepTrial = 2;
    kOverlapTrial = 3;
    numTrialTypes = 3;
    
    kBlankStim = 1;                  % stimulus images
    kLeftStim = 2;
    kRightStim = 3;
    kBothStim = 4;
    kTestStim = 5;
    
    kLeft = -1;
    kRight = 1;
  end
  
  properties (Access = public)
    blocksDone;            % number of blocks completed
    calTrialsDone         % number of calibration trials done
    dataState = RTDataState.dataIdle; % state for data collection system
    fixOffTimeS = 0;      % trial time when fixation point went off
    gapDurS = 0.200;      % duration of fixation gap
    lbj                   % handle for LabJack object
    numSummed = 0;        % number of summed traces
    posAvg;               % the average position trace
    posSummed;            % number of position traces summed
    posTrace;             % the position trace
    prestimDurS;          % duration of the prestim period
    prestimTimeS;         % time prestim period start on current trial
    rawData;              % trace of unfiltered data
    saccadeDurS;          % duration of sample epoch
    saccadeSamples;       % number of samples included in saccade
    samplesRead = 0;      % number of samples read in current sweep
    stepDirection = 0;    % direction of the current step
    stepSizeDeg = 10.0;   % size of the stimulus steps
    taskMode;             % taskMode is set in OpenRT, not here
    taskState = RTTaskState.taskStarttrial; % state for task system
    targetTimeS = 0;      % time target appeared in current trial
    trialDurS;
    trialSamples;         % number of samples in a trial
    trialStartTimeVec = []; % trial start as a time vector
    trialType;            % type of current trial
    trialTypesDone;       % count of different trial types completed
    velAvg;               % average velocity trace
    velSummed;            % summed trace of velocities
    velTrace;             % most recent velocity trace
    voltage = 0;          % the output voltage used in debugging
  end
 
  methods (Access = private)
    
    function clearTraces(app)
      app.blocksDone = 0;
      app.calTrialsDone = 0;
      app.numSummed = 0;
      app.posTrace = zeros(app.trialSamples, 1);                      % trial RT position trace
      app.posSummed = zeros(app.saccadeSamples, 1);                   % summed position traces
      app.posAvg = zeros(app.saccadeSamples, 1);                      % averaged position traces
      app.rawData = zeros(app.trialSamples, app.lbj.numChannels);   	% raw data
      app.saccadeDurS = zeros(1, app.numTrialTypes);                	% average saccade durations
      app.trialTypesDone = zeros(1, app.numTrialTypes);               % table of completed trials in block
      app.velTrace = zeros(app.trialSamples, 1);                      % trial RT velocity trace
      app.velSummed = zeros(app.saccadeSamples, 1);                   % summed position traces
      app.velAvg = zeros(app.saccadeSamples, 1);                      % averaged position traces
   end
    
    function closeRT(app, ~, ~, handles)
      % this function is called  when the user closes the main window
      % close the timer and clear the LabJack handle
      %
      % fist check whether the task is running
      if strcmp(get(handles.startButton, 'String'), 'Stop')
        return;
      end
      selection = uiconfirm(app.figure1, 'Really exit? Unsaved data will be lost.', 'Exit Request');
      switch selection
        case 'OK'
          try delete(handles.visStim); catch, end
          try stop(timerfind); catch, end
          try delete(timerfind); catch, end
          try clear('app.lbj'); catch, end
          delete(app.figure1);                                                            % close the program window
        case 'Cancel'
          return
      end
    end
            
    function collectData(~, obj, ~, app)
      % reads stream data from the LabJack
      handles = obj.UserData;
%       data = handles.data;
      switch app.dataState
        case RTDataState.dataIdle
          return;
        case RTDataState.dataCollect
          [dRaw, ~] = getStreamData(app.lbj);                             %#ok<*NASGU> % get stream data
          numNew = min(length(dRaw), app.trialSamples - app.samplesRead);
          app.rawData(app.samplesRead + 1:app.samplesRead + numNew, :) = dRaw(1:numNew, :);
          app.samplesRead = app.samplesRead + numNew;
          if (app.samplesRead == app.trialSamples)
            tStart = tic;
            app.lbj.verbose = 0;
            stopStream(app.lbj);
            app.lbj.verbose = 1;
            app.dataState = RTDataState.dataIdle;
            app.taskState = RTTaskState.taskEndtrial;
          end
      end
    end
    
    function setupLabJack(app)
      %  get hardware info and do not continue if daq device/drivers unavailable
      
      app.lbj = labJackU6;                        % create the daq object
      open(app.lbj);                              % open connection to the daq
      if isempty(app.lbj.handle)
        originalSize = get(0, 'DefaultUIControlFontSize');
        set(0, 'DefaultUIControlFontSize', 14);
        questdlg('Exit and check USB connections.', ...
          'No LabJack Device Found', 'OK', 'OK');
        set(0, 'DefaultUIControlFontSize', originalSize);
      else
        fprintf(1,'Reaction Time: LabJack Ready.\n\n');
      end
      % create input channel list
      removeChannel(app.lbj, -1);                 % remove all input channels
      addChannel(app.lbj, 0, 10, ['s' 's']);      % add channel 0 as input
      app.lbj.SampleRateHz = 1000;                % sample rate (Hz)
      app.lbj.ResolutionADC = 1;                  % ADC resolution (AD bit depth)
      
      % configure LabJack for analog input streaming
      
      errorCode = streamConfigure(app.lbj);
      if errorCode > 0
        fprintf(1,'Reaction Time: Unable to configure LabJack. Error %d.\n',errorCode);
        return
      end
    end
    
  function taskController(app, obj, ~, ~)
    
    handles = obj.UserData;
%     data = handles.data;
    visStim = handles.visStim;
    saccades = handles.saccades;
    rtDists = handles.rtDists;
    switch app.taskState
      case RTTaskState.taskStarttrial
        positionWindow(visStim);
        if sum(app.trialTypesDone) >= app.numTrialTypes          	% finished another block
          app.trialTypesDone = zeros(1, app.numTrialTypes);    	% clear counters
          app.blocksDone = app.blocksDone + 1;                  % increment block counter
        end
        if atStepRangeLimit(visStim, app)
          app.trialType = app.kCenteringTrial;
        else
          app.trialType = ceil(rand() * app.numTrialTypes);     % randomly selected saccade step
          startIndex = app.trialType;                            % mark the index where search starts
          while true                                             	% find an unused, doable step
            if app.trialTypesDone(app.trialType) == 0         % unused and doable
              break;
            end
            app.trialType = mod(app.trialType, app.numTrialTypes) + 1; % try the next one
            assert(app.trialType ~= startIndex, 'SaccadeRT taskController: failed to find unused trial type');                     % no types available (shouldn't happen)
          end
          if rand() > 0.5
            app.stepDirection = app.kLeft;
          else
            app.stepDirection = app.kRight;
          end
        end
        if app.taskMode == app.kTiming
          prepareTimingImages(visStim, app, app.trialType);
        else
          prepareImages(visStim, app);
        end
        if app.taskMode == app.kDebug
          app.voltage = min(5.0, visStim.currentOffsetPix / 1000.0);  % debugging - DACO0 should go to AIN0
          analogOut(app.lbj, 0, 2.5 + app.voltage);
        end
        app.trialStartTimeVec = clock;                          	% get a valid start for settle time
        app.taskState = RTTaskState.taskSettle;                  	% go to settle state
      case RTTaskState.taskSettle
        if etime(clock, app.trialStartTimeVec) > 0.015           % data settled for one taskTimer cycle
          app.prestimTimeS = app.prestimDurS + rand() * 0.125;	% jitter the stimon time a bit
          if app.trialType == app.kGapTrial             	% gap trials have target after the gap
            app.targetTimeS = app.prestimTimeS + app.gapDurS;
          else
            app.targetTimeS = app.prestimTimeS;              	% all others have target before the gap
          end
          if app.trialType == app.kOverlapTrial        	% overlap trials have fixOff after the gap
            app.fixOffTimeS = app.prestimTimeS + app.gapDurS;
          else                                                    % all others have fixOff before the gap
            app.fixOffTimeS = app.prestimTimeS;
          end
          app.samplesRead = 0;
          app.lbj.verbose = 0;
          startStream(app.lbj);
          app.trialStartTimeVec = clock;                      	% reset the trial clock to data collection
          app.lbj.verbose = 1;
          app.dataState = RTDataState.dataCollect;
          app.taskState = RTTaskState.taskPrestim;              	% go to prestim state
        end
      case RTTaskState.taskPrestim
        trialTimeS = etime(clock, app.trialStartTimeVec);
        if trialTimeS > app.prestimTimeS	% preStim time up?
          if app.trialType == app.kCenteringTrial
            drawCenterStimulus(visStim, app);
          else
            drawImage(visStim, visStim.gapStim);
          end
          if app.trialType ~= app.kGapTrial           	    % gap trials have target after the gap
            app.targetTimeS = trialTimeS;
          end
          if app.trialType ~= app.kOverlapTrial       	% overlap trials have fixoff after the gap
            app.fixOffTimeS = trialTimeS;
          end
          app.taskState = RTTaskState.taskGapstim;        % go to gap state
        end
      case RTTaskState.taskGapstim
        trialTimeS = etime(clock, app.trialStartTimeVec);
        if trialTimeS > app.prestimTimeS + app.gapDurS
          drawImage(visStim, visStim.finalStim);          % gap time up, draw postgap stim
          if app.trialType == app.kGapTrial               % gap trials have target after the gap
            app.targetTimeS = trialTimeS;
          end
          if app.trialType == app.kOverlapTrial       	  % overlap trials have fixoff after the gap
            app.fixOffTimeS = trialTimeS;
          end
          app.taskState = RTTaskState.taskPoststim;       % collectData pushes us from poststim to endtrial
        end
      case RTTaskState.taskEndtrial
        fprintf('taskEndtrial 0\n');
        if app.trialType ~= app.kCenteringTrial          	% no updates needed on centering trials
          fprintf('taskEndtrial 0.0\n');
          [startIndex, endIndex] = processSignals(saccades, app);
          fprintf('taskEndtrial 0.1\n');
          doPlots(handles.posVelPlots, app, handles, startIndex, endIndex);
          fprintf('taskEndtrial 0.2\n');
          if startIndex > 0
            addRT(rtDists{app.trialType}, (startIndex / app.lbj.SampleRateHz  - app.targetTimeS) * 1000.0);
          end
        fprintf('taskEndtrial 1\n');
          if (mod(sum(app.numSummed), app.numTrialTypes) == 0)	% finished a block
            fprintf('taskEndtrial before plot\n');
            needsRescale = doPlots(rtDists{app.trialType});
            if needsRescale > 0
              for i = 1:app.numTrialTypes
                fprintf('taskEndtrial doing rescale\n');
                rescale(rtDists{i}, needsRescale);
%                 doPlots(rtDists{i});
              end
            end
          end
          set(handles.calibrationText, 'string', sprintf('Calibration %.1f deg/V', saccades.degPerV));
        end
        app.trialStartTimeVec = [];                                % why do we need to clear this?
        app.taskState = RTTaskState.taskStarttrial;
   end
  end
        
  function taskError(~, ~, ~, ~)
    fprintf('timer error\n')
  end
        
end
    

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function openRT(app, varargin)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
      
      % This function has no output args, see OutputFcn.
      % hObject    handle to figure
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)
      % varargin   command line arguments to RT (see VARARGIN)
      
      % test mode requires connecting DAC0 to AIN0 and DAC1 to AIN1 on the LabJack
      if ~isempty(varargin)
        if strcmp(varargin{1}, 'debug') || strcmp(varargin{1}, 'test')
          app.taskMode = app.kDebug;
        end
      else
        app.taskMode = app.kNormal;
      end
      
      %     taskMode = app.kTiming;
      app.taskMode = app.kDebug;
      
      switch app.taskMode
        case app.kDebug
          set(handles.warnText, 'string', 'Debug Mode');
        case app.kTiming
          set(handles.warnText, 'string', 'Timing Test');
        otherwise
      end
      app.trialDurS = max(1.0, 2 * app.saccadeTraceS);
      app.prestimDurS = min(app.trialDurS / 4, 0.250);
      handles.output = hObject;                                               % select default command line output
      set(hObject, 'CloseRequestFcn', {@app.closeRT, handles});               % close function will close LabJack
      setupLabJack(app);
      handles.saccades = RTSaccades(app);                                     % RTSaccades need LBJ set up first
      app.saccadeSamples = floor(app.saccadeTraceS * app.lbj.SampleRateHz);
      app.trialSamples = floor(app.trialDurS * app.lbj.SampleRateHz);
      clearTraces(app);

      handles.visStim = RTStimulus(app);
      handles.posVelPlots = RTPosVelPlots(handles);
      axes = [handles.gapAxes handles.stepAxes handles.overlapAxes];
      handles.RTDist = cell(1, app.numTrialTypes);
      for i = 1:length(axes)
        handles.rtDists{i} = RTDist(app, i, axes(i));
      end
      
      % set up test mode
%       app.taskMode = taskMode;
      %     if (app.taskMode == app.kDebug)
      %         analogOut(handles.lbj, 0, 2.5);                               % For debugging (AOuts to AIns)
      %     end
      movegui(hObject, 'northeast');
      guidata(hObject, handles);                                              % save the selection
    end

    % Callback function
    function Filter60Hz_Callback(app, event)
            
    end

    % Button pushed function: clearButton
    function clearButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);
      selection = uiconfirm(app.figure1, 'Really clear all data? (This cannot be undone).', 'Clear Data');
      switch selection
        case 'OK'
          clearAll(handles.saccades, app);
          for i = 1:app.numTrialTypes
              clearAll(handles.rtDists{i}, app);
          end
          clearTraces(app);
          app.fixOffTimeS = 0;                                      % stop plots from showing stale trial times
          app.targetTimeS = 0;
          doPlots(handles.posVelPlots, app, handles, 0, 0);
          set(handles.calibrationText, 'string', '');
          guidata(hObject, handles);
      end
    end

    % Button pushed function: loadDataButton
    function loadDataButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);
      
      %
      RTControlState(app, handles, 'off', {})
      [fileName, filePath] = uigetfile('*.mat', 'Load Matlab Data Workspace', '~/Desktop');
      if fileName ~= 0
        tMode = app.taskMode;                               % keep taskMode across data loads
        load([filePath fileName], 's', 'r1', 'r2', 'r3');
%         handles.data = d;
        app.taskMode = tMode;                               % keep taskMode across data loads
        handles.saccades = s;
        set(handles.calibrationText, 'string', sprintf('Calibration %.1f deg/V', handles.saccades.degPerV));
        handles.rtDists{1} = r1;
        handles.rtDists{2} = r2;
        handles.rtDists{3} = r3;

        % saved axes handles generally aren't valid if we are in a new run.
        % Load the handles with the current axes handles.
        handles.rtDists{1}.fHandle = app.gapAxes;
        handles.rtDists{2}.fHandle = app.stepAxes;
        handles.rtDists{3}.fHandle = app.overlapAxes;
        guidata(hObject, handles);                                      % save the selections

        [startIndex, endIndex] = processSignals(handles.saccades, app);
        plot(handles.posVelPlots, app, handles, startIndex, endIndex);
        for i = app.kGapTrial:app.kOverlapTrial
          plot(handles.rtDists{i});
        end
      end
      RTControlState(app, handles, 'on', {})
    end

    % Button pushed function: saveDataButton
    function saveDataButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      
      % Saving the workspace for a GUI isn't simple.  What we have accessible in this
      % environment is mostly the handles.  If we save, it's an attempt to save
      % handles, which doesn't work.  Instead, we get list of all the properties
      % from the RTTaskData and RTDist classes, and then use eval statement to assign those to
      % local variable and save them.
      RTControlState(app, handles, 'off', {})
      [fileName, filePath] = uiputfile('*.mat', 'Save Matlab Data Workspace', '~/Desktop/RTData.mat');
      if fileName ~= 0
%         d = handles.data;
        s = handles.saccades;
        r1 = handles.rtDists{1};
        r2 = handles.rtDists{2};
        r3 = handles.rtDists{3};
        save([filePath fileName], 's', 'r1', 'r2', 'r3');
      end
      RTControlState(app, handles, 'on', {})
    end

    % Button pushed function: savePlotsButton
    function savePlotsButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      
      RTControlState(app, handles, 'off', {})
      progress = uiprogressdlg(app.figure1, 'title', 'Save Plots', 'message', 'Saving to ~/Desktop/RTData', ...
        'indeterminate','on');
      timeString = savePlot(app.posAxes, '~/Desktop/RTData/Position/', 'RT', 'png');
      savePlot(app.velAxes, '~/Desktop/RTData/Velocity/', 'RT', 'png', timeString);
      savePlot(app.gapAxes, '~/Desktop/RTData/Gap/', 'RT', 'png', timeString);       
      savePlot(app.stepAxes, '~/Desktop/RTData/Step/', 'RT', 'png', timeString);       
      savePlot(app.overlapAxes, '~/Desktop/RTData/Overlap/', 'RT', 'png', timeString);       
      close(progress);
      RTControlState(app, handles, 'on', {})
    end

    % Button pushed function: startButton
    function startButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      
      % hObject    handle to startButton (see GCBO)
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)
      
      if strcmp(get(handles.startButton, 'String'), 'Start') % if start button, do the following
        fprintf(1,'\nReaction Time v1.0\n %s\n', datestr(clock));
        viewDistanceCM = str2double(get(handles.viewDistanceText, 'string'));
        if viewDistanceCM > app.maxViewDistCM
          errordlg(sprintf('Viewing distance must be <= %d cm', floor(app.maxViewDistCM)));
          return;
        end
        setViewDistanceCM(handles.visStim, app, str2double(get(handles.viewDistanceText, 'string')));
        handles.saccades.thresholdDeg = str2double(get(handles.thresholdDegText, 'string'));
        % create timer to control the task
        taskRateHz = 25;
        handles.taskTimer = timer('Name', 'TaskTimer', 'ExecutionMode', 'fixedRate',...
          'Period', 1.0/taskRateHz, 'UserData', handles, 'ErrorFcn', {@app.taskError, handles}, 'TimerFcn',...
          {@app.taskController, [handles.posAxes handles.velAxes]});
        
        % create timer to get data from LabJack
        app.taskState = RTTaskState.taskStarttrial;
        app.trialStartTimeVec = [];
        app.dataState = RTDataState.dataIdle;
        dataCollectRateHz = 25;                       % Fast enough to prevent overflow w/o blocking other activity
        handles.dataTimer = timer('Name', 'LabJackData', 'ExecutionMode', 'fixedRate',...
          'Period', 1.0 / dataCollectRateHz, 'UserData', handles, 'ErrorFcn', {@app.taskError, handles},...
          'TimerFcn', {@app.collectData, app}, 'StartDelay', 0.050); % StartDelay allows other parts of the gui to execute
        
        % set the gui button to "running" state
        set(handles.startButton, 'String', 'Stop', 'BackgroundColor', 'red');
        RTControlState(app, handles, 'off', {handles.startButton})
        % save timers to handles and update the GUI display
        
        %% Start plots, data pickup, and data acquisition
        start(handles.dataTimer);
        start(handles.taskTimer);
        
        %% Stop -- we're already running, so it's a the stop button
      else % stop
        stop(timerfind);                                               	% stop/delete timers; pause data stream
        delete(timerfind);
        handles.dataTimer = 0;
        handles.taskTimer = 0;
        stopStream(app.lbj);
        set(handles.startButton, 'string', 'Start','backgroundColor', 'green');
        RTControlState(app, handles, 'on', {handles.startButton})
        drawnow;
        drawCenterStimulus(handles.visStim, app);                           	% recenter fixspot
      end
      guidata(hObject, handles);
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.figure1 = uifigure('Visible', 'off');
      app.figure1.Name = 'NSCI 20100';
      app.figure1.Tag = 'figure1';
      app.posAxes = uiaxes(app.figure1);
      app.posAxes.Tag = 'posAxes';
      app.velAxes = uiaxes(app.figure1);
      app.velAxes.Tag = 'velAxes';
      app.gapAxes = uiaxes(app.figure1);
      app.gapAxes.Tag = 'gapAxes';
      app.stepAxes = uiaxes(app.figure1);
      app.stepAxes.Tag = 'stepAxes';
      app.overlapAxes = uiaxes(app.figure1);
      app.overlapAxes.Tag = 'overlapAxes';
      app.startButton = uibutton(app.figure1, 'push');
      app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButton_Callback, true);
      app.startButton.Tag = 'startButton';
      app.startButton.Text = 'Start';
      app.clearButton = uibutton(app.figure1, 'push');
      app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButton_Callback, true);
      app.clearButton.Tag = 'clearButton';
      app.clearButton.Text = 'Clear Data';
      app.viewDistanceText = uieditfield(app.figure1, 'text');
      app.viewDistanceText.Tag = 'viewDistanceText';
      app.viewDistanceText.Value = '50';
      app.text2 = uilabel(app.figure1);
      app.text2.Tag = 'text2';
      app.text2.Text = 'Viewing Distance (cm):';
      app.thresholdDegText = uieditfield(app.figure1, 'text');
      app.thresholdDegText.Tag = 'thresholdDegText';
      app.thresholdDegText.Value = '3';
      app.text3 = uilabel(app.figure1);
      app.text3.Tag = 'text3';
      app.text3.Text = 'Saccade Threshold (deg):';
      app.savePlotsButton = uibutton(app.figure1, 'push');
      app.savePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @savePlotsButton_Callback, true);
      app.savePlotsButton.Tag = 'savePlotsButton';
      app.savePlotsButton.Text = 'Save Plots';
      app.titleText = uilabel(app.figure1);
      app.titleText.Tag = 'titleText';
      app.titleText.Text = 'NSCI 20100 Reaction Time Laboratory';
      app.saveDataButton = uibutton(app.figure1, 'push');
      app.saveDataButton.ButtonPushedFcn = createCallbackFcn(app, @saveDataButton_Callback, true);
      app.saveDataButton.Tag = 'saveDataButton';
      app.saveDataButton.Text = 'Save Data';
      app.loadDataButton = uibutton(app.figure1, 'push');
      app.loadDataButton.ButtonPushedFcn = createCallbackFcn(app, @loadDataButton_Callback, true);
      app.loadDataButton.Tag = 'loadDataButton';
      app.loadDataButton.Text = 'Load Data';
      app.warnText = uilabel(app.figure1);
      app.warnText.Tag = 'warnText';
      app.warnText.Text = '';
      app.calibrationText = uilabel(app.figure1);
      app.calibrationText.Tag = 'calibrationText';
      app.calibrationText.Text = '';
      app.Filter60Hz = uicheckbox(app.figure1);
      app.Filter60Hz.Tag = 'Filter60Hz';
      app.Filter60Hz.Text = 'Filter 60 Hz';
      % Show the figure after all components are created
      app.figure1.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = SaccadeRT(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.figure1)

      % Execute the startup function
      runStartupFcn(app, @(app)openRT(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure1)
    end
  end
end